classdef fats2parte < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Button                  matlab.ui.control.Button
        Button_2                matlab.ui.control.Button
        Button_3                matlab.ui.control.Button
        Button_4                matlab.ui.control.Button
        Button_5                matlab.ui.control.Button
        Button_6                matlab.ui.control.Button
        Button_7                matlab.ui.control.Button
        Button_8                matlab.ui.control.Button
        Button_9                matlab.ui.control.Button
        Button_10               matlab.ui.control.Button
        Button_11               matlab.ui.control.Button
        Button_12               matlab.ui.control.Button
        Button_13               matlab.ui.control.Button
        Button_14               matlab.ui.control.Button
        Button_15               matlab.ui.control.Button
        Button_16               matlab.ui.control.Button
        Button_17               matlab.ui.control.Button
        Button_18               matlab.ui.control.Button
        Button_19               matlab.ui.control.Button
        Button_20               matlab.ui.control.Button
        VamoadarleButton        matlab.ui.control.Button
        Jugador1EditFieldLabel  matlab.ui.control.Label
        Jugador1                matlab.ui.control.NumericEditField
        Jugador2EditFieldLabel  matlab.ui.control.Label
        Jugador2                matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        % Description
        % i - almacena los clicks que se dan
        % j - almacena la posición de la celda
        %count - almacena el número total de clicks
        a; 
        b;
        c;
        
        mensaje;
        bandera = 0;
        
        path1;
        path2;
        
        i = 0;
        j = 0;
        
        score1 = 0;
        score2 = 0;
        
        gray1;
        gray2;
        
        ima;
        ima2;
        
        dcta;
        dctb;
        
        matA;
        matB;
        
        count = 0;
        but1;
        but2;
        but3;
        but4;
        but5;
        but6;
        but7;
        but8;
        but9;
        but10;
        but11;
        but12;
        but13;
        but14;
        but15;
        but16;
        but17;
        but18;
        but19;
        but20;
    end
    
    methods (Access = public)
        
        function func2(app)
            if (app.j == 1)
                app.Button.Enable = 'off';
            
            elseif(app.j == 2)
                app.Button_2.Enable = 'off';
                
            elseif(app.j == 3)
                app.Button_3.Enable = 'off';
                
            elseif(app.j == 4)
                app.Button_4.Enable = 'off';
           
            elseif(app.j == 5)
                app.Button_5.Enable = 'off';

            elseif(app.j == 6)
                app.Button_6.Enable = 'off';

            elseif(app.j == 7)
                app.Button_7.Enable = 'off';

            elseif(app.j == 8)
                app.Button_8.Enable = 'off';

            elseif(app.j == 9)
                app.Button_9.Enable = 'off';
                
            elseif(app.j == 10)
                app.Button_10.Enable = 'off';
                
            elseif(app.j == 11)
                app.Button_11.Enable = 'off';
                
            elseif(app.j == 12)
                app.Button_12.Enable = 'off';
                
            elseif(app.j == 13)
                app.Button_13.Enable = 'off';
                
            elseif(app.j == 14)
                app.Button_14.Enable = 'off';
                
            elseif(app.j == 15)
                app.Button_15.Enable = 'off';
                
            elseif(app.j == 16)
                app.Button_16.Enable = 'off';
                
            elseif(app.j == 17)
                app.Button_17.Enable = 'off';
                
            elseif(app.j == 18)
                app.Button_18.Enable = 'off';
                
            elseif(app.j == 19)
                app.Button_19.Enable = 'off';
                
            elseif(app.j == 20)
                app.Button_20.Enable = 'off';
                
            end
        end
        
        function func3(app)
            
            if(app.j == 1)
                app.Button.Icon = '';
            
            elseif (app.j == 2)
                app.Button_2.Icon = '';
                
            elseif (app.j == 3)
                app.Button_3.Icon = '';
                
            elseif (app.j == 4)
                app.Button_4.Icon = '';
                
            elseif (app.j == 5)
                app.Button_5.Icon = '';
                
            elseif (app.j == 6)
                app.Button_6.Icon = '';
                
            elseif (app.j == 7)
                app.Button_7.Icon = '';
                
            elseif (app.j == 8)
                app.Button_8.Icon = '';
                
            elseif (app.j == 9)
                app.Button_9.Icon = '';
                
            elseif (app.j == 10)
                app.Button_10.Icon = '';
                
            elseif (app.j == 11)
                app.Button_11.Icon = '';
                
            elseif (app.j == 12)
                app.Button_12.Icon = '';
                
            elseif (app.j == 13)
                app.Button_13.Icon = '';
                
            elseif (app.j == 14)
                app.Button_14.Icon = '';
                
            elseif (app.j == 15)
                app.Button_15.Icon = '';
                
            elseif (app.j == 16)
                app.Button_16.Icon = '';
                
            elseif (app.j == 17)
                app.Button_17.Icon = '';
                
            elseif (app.j == 18)
                app.Button_18.Icon = '';
                
            elseif (app.j == 18)
                app.Button_18.Icon = '';
                
            elseif (app.j == 19)
                app.Button_19.Icon = '';
                
            elseif (app.j == 20)
                app.Button_20.Icon = '';
                
            end
        end
        
        function status = checkImgs(app, img1, img2)
            
            app.path1 = strcat(num2str(img1), '.jpeg');
            app.path2 = strcat(num2str(img2), '.jpeg');
              
%             disp(app.ima);
%             disp(app.ima2);
              
              
            %'img1.jpeg'
            app.ima = imread(app.path1);
            app.ima2 = imread(app.path2);
            
            app.gray1 = rgb2gray(app.ima);
            app.gray2 = rgb2gray(app.ima2);
            app.dcta = dct2(app.gray1);
            app.dctb = dct2(app.gray2);
            
            
            %por que nisiquera era una matrix
            app.matA = app.dcta(1:8,1:8);
            app.matB = app.dctb(1:8,1:8);
            
            if(app.matA == app.matB)
                status = 1;
            else
                status = 0;
                
            end
            app.dcta = [];
            app.dctb = [];
            app.matA = [];
            app.matB = [];
            
        end
        
        function result1(app)
            app.Jugador1.Value = app.score1;
            app.Jugador2.Value = app.score2;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: VamoadarleButton
        function VamoadarleButtonPushed(app, event)
           app.a = [1:10 1:10];
           app.b = randperm(20);
           app.c = app.a(app.b(1:20));


           %Boton 1
           if(app.c(1)== 1)
               app.Button.Icon = '1.jpeg';
               app.but1 = '1.jpeg';
           
           elseif (app.c(1) == 2)
               app.Button.Icon = '2.jpeg';
               app.but1 = '2.jpeg';
               
           elseif (app.c(1) == 3)
               app.Button.Icon = '3.jpeg';
               app.but1 = '3.jpeg';
           
           elseif (app.c(1) == 4)
               app.Button.Icon = '4.jpeg';
               app.but1 = '4.jpeg';
           
           elseif (app.c(1) == 5)
               app.Button.Icon = '5.jpeg';
               app.but1 = '5.jpeg';
               
           elseif (app.c(1) == 5)
               app.Button.Icon = '5.jpeg';
               app.but1 = '5.jpeg';
           
           elseif (app.c(1) == 6)
               app.Button.Icon = '6.jpeg';
               app.but1 = '6.jpeg';
               
           elseif (app.c(1) == 7)
               app.Button.Icon = '7.jpeg';
               app.but1 = '7.jpeg';
           
           elseif (app.c(1) == 8)
               app.Button.Icon = '8.jpeg';
               app.but1 = '8.jpeg';
               
           elseif (app.c(1) == 9)
               app.Button.Icon = '9.jpeg';
               app.but1 = '9.jpeg';
           
           else 
               app.Button.Icon = '10.jpeg';
               app.but1 = '10.jpeg';
           end
           
           %Boton 2
           if(app.c(2)== 1)
               app.Button_2.Icon = '1.jpeg';
               app.but2 = '1.jpeg';
           
           elseif (app.c(2) == 2)
               app.Button_2.Icon = '2.jpeg';
               app.but2 = '2.jpeg';
               
           elseif (app.c(2) == 3)
               app.Button_2.Icon = '3.jpeg';
               app.but2 = '3.jpeg';
           
           elseif (app.c(2) == 4)
               app.Button_2.Icon = '4.jpeg';
               app.but2 = '4.jpeg';
           
           elseif (app.c(2) == 5)
               app.Button_2.Icon = '5.jpeg';
               app.but2 = '5.jpeg';
               
           elseif (app.c(2) == 5)
               app.Button_2.Icon = '5.jpeg';
               app.but2 = '5.jpeg';
           
           elseif (app.c(2) == 6)
               app.Button_2.Icon = '6.jpeg';
               app.but2 = '6.jpeg';
               
           elseif (app.c(2) == 7)
               app.Button_2.Icon = '7.jpeg';
               app.but2 = '7.jpeg';
           
           elseif (app.c(2) == 8)
               app.Button_2.Icon = '8.jpeg';
               app.but2 = '8.jpeg';
               
           elseif (app.c(2) == 9)
               app.Button_2.Icon = '9.jpeg';
               app.but2 = '9.jpeg';
           
           else 
               app.Button_2.Icon = '10.jpeg';
               app.but2 = '10.jpeg';
           end
           
           %Boton 3
           if(app.c(3)== 1)
               app.Button_3.Icon = '1.jpeg';
               app.but3 = '1.jpeg';
           
           elseif (app.c(3) == 2)
               app.Button_3.Icon = '2.jpeg';
               app.but3 = '2.jpeg';
               
           elseif (app.c(3) == 3)
               app.Button_3.Icon = '3.jpeg';
               app.but3 = '3.jpeg';
           
           elseif (app.c(3) == 4)
               app.Button_3.Icon = '4.jpeg';
               app.but3 = '4.jpeg';
           
           elseif (app.c(3) == 5)
               app.Button_3.Icon = '5.jpeg';
               app.but3 = '5.jpeg';
               
           elseif (app.c(3) == 5)
               app.Button_3.Icon = '5.jpeg';
               app.but3 = '5.jpeg';
           
           elseif (app.c(3) == 6)
               app.Button_3.Icon = '6.jpeg';
               app.but3 = '6.jpeg';
               
           elseif (app.c(3) == 7)
               app.Button_3.Icon = '7.jpeg';
               app.but3 = '7.jpeg';
           
           elseif (app.c(3) == 8)
               app.Button_3.Icon = '8.jpeg';
               app.but3 = '8.jpeg';
               
           elseif (app.c(3) == 9)
               app.Button_3.Icon = '9.jpeg';
               app.but3 = '9.jpeg';
           
           else 
               app.Button_3.Icon = '10.jpeg';
               app.but3 = '10.jpeg';
           end
           
           %Boton 4
           if(app.c(4)== 1)
               app.Button_4.Icon = '1.jpeg';
               app.but4 = '1.jpeg';
           
           elseif (app.c(4) == 2)
               app.Button_4.Icon = '2.jpeg';
               app.but4 = '2.jpeg';
               
           elseif (app.c(4) == 3)
               app.Button_4.Icon = '3.jpeg';
               app.but4 = '3.jpeg';
           
           elseif (app.c(4) == 4)
               app.Button_4.Icon = '4.jpeg';
               app.but4 = '4.jpeg';
           
           elseif (app.c(4) == 5)
               app.Button_4.Icon = '5.jpeg';
               app.but4 = '5.jpeg';
               
           elseif (app.c(4) == 5)
               app.Button_4.Icon = '5.jpeg';
               app.but4 = '5.jpeg';
           
           elseif (app.c(4) == 6)
               app.Button_4.Icon = '6.jpeg';
               app.but4 = '6.jpeg';
               
           elseif (app.c(4) == 7)
               app.Button_4.Icon = '7.jpeg';
               app.but4 = '7.jpeg';
           
           elseif (app.c(4) == 8)
               app.Button_4.Icon = '8.jpeg';
               app.but4 = '8.jpeg';
               
           elseif (app.c(4) == 9)
               app.Button_4.Icon = '9.jpeg';
               app.but4 = '9.jpeg';
           
           else 
               app.Button_4.Icon = '10.jpeg';
               app.but4 = '10.jpeg';
           end
           
           %Boton 5
           if(app.c(5)== 1)
               app.Button_5.Icon = '1.jpeg';
               app.but5 = '1.jpeg';
           
           elseif (app.c(5) == 2)
               app.Button_5.Icon = '2.jpeg';
               app.but5 = '2.jpeg';
               
           elseif (app.c(5) == 3)
               app.Button_5.Icon = '3.jpeg';
               app.but5 = '3.jpeg';
           
           elseif (app.c(5) == 4)
               app.Button_5.Icon = '4.jpeg';
               app.but5 = '4.jpeg';
           
           elseif (app.c(5) == 5)
               app.Button_5.Icon = '5.jpeg';
               app.but5 = '5.jpeg';
               
           elseif (app.c(5) == 5)
               app.Button_5.Icon = '5.jpeg';
               app.but5 = '5.jpeg';
           
           elseif (app.c(5) == 6)
               app.Button_5.Icon = '6.jpeg';
               app.but5 = '6.jpeg';
               
           elseif (app.c(5) == 7)
               app.Button_5.Icon = '7.jpeg';
               app.but5 = '7.jpeg';
           
           elseif (app.c(5) == 8)
               app.Button_5.Icon = '8.jpeg';
               app.but5 = '8.jpeg';
               
           elseif (app.c(5) == 9)
               app.Button_5.Icon = '9.jpeg';
               app.but5 = '9.jpeg';
           
           else 
               app.Button_5.Icon = '10.jpeg';
               app.but5 = '10.jpeg';
           end
           
           %Boton 6
           if(app.c(6)== 1)
               app.Button_6.Icon = '1.jpeg';
               app.but6 = '1.jpeg';
           
           elseif (app.c(6) == 2)
               app.Button_6.Icon = '2.jpeg';
               app.but6 = '2.jpeg';
               
           elseif (app.c(6) == 3)
               app.Button_6.Icon = '3.jpeg';
               app.but6 = '3.jpeg';
           
           elseif (app.c(6) == 4)
               app.Button_6.Icon = '4.jpeg';
               app.but6 = '4.jpeg';
           
           elseif (app.c(6) == 5)
               app.Button_6.Icon = '5.jpeg';
               app.but6 = '5.jpeg';
               
           elseif (app.c(6) == 5)
               app.Button_6.Icon = '5.jpeg';
               app.but6 = '5.jpeg';
           
           elseif (app.c(6) == 6)
               app.Button_6.Icon = '6.jpeg';
               app.but6 = '6.jpeg';
               
           elseif (app.c(6) == 7)
               app.Button_6.Icon = '7.jpeg';
               app.but6 = '7.jpeg';
           
           elseif (app.c(6) == 8)
               app.Button_6.Icon = '8.jpeg';
               app.but6 = '8.jpeg';
               
           elseif (app.c(6) == 9)
               app.Button_6.Icon = '9.jpeg';
               app.but6 = '9.jpeg';
           
           else 
               app.Button_6.Icon = '10.jpeg';
               app.but6 = '10.jpeg';
           end
           
           %Boton 7
           if(app.c(7) == 1)
               app.Button_7.Icon  = '1.jpeg';
               app.but7 = '1.jpeg';
           
           elseif (app.c(7)  == 2)
               app.Button_7.Icon  = '2.jpeg';
               app.but7 = '2.jpeg';
               
           elseif (app.c(7)  == 3)
               app.Button_7.Icon  = '3.jpeg';
               app.but7 = '3.jpeg';
           
           elseif (app.c(7)  == 4)
               app.Button_7.Icon  = '4.jpeg';
               app.but7 = '4.jpeg';
           
           elseif (app.c(7)  == 5)
               app.Button_7.Icon  = '5.jpeg';
               app.but7 = '5.jpeg';
               
           elseif (app.c(7)  == 5)
               app.Button_7.Icon  = '5.jpeg';
               app.but7 = '5.jpeg';
           
           elseif (app.c(7)  == 6)
               app.Button_7.Icon  = '6.jpeg';
               app.but7 = '6.jpeg';
               
           elseif (app.c(7)  == 7)
               app.Button_7.Icon  = '7.jpeg';
               app.but7 = '7.jpeg';
           
           elseif (app.c(7)  == 8)
               app.Button_7.Icon  = '8.jpeg';
               app.but7 = '8.jpeg';
               
           elseif (app.c(7)  == 9)
               app.Button_7.Icon  = '9.jpeg';
               app.but7 = '9.jpeg';
           
           else 
               app.Button_7.Icon  = '10.jpeg';
               app.but7 = '10.jpeg';
           end
           
           %Boton 8
           if(app.c(8)  == 1)
               app.Button_8.Icon   = '1.jpeg';
               app.but8 = '1.jpeg';
           
           elseif (app.c(8)   == 2)
               app.Button_8.Icon   = '2.jpeg';
               app.but8 = '2.jpeg';
               
           elseif (app.c(8)   == 3)
               app.Button_8.Icon   = '3.jpeg';
               app.but8 = '3.jpeg';
           
           elseif (app.c(8)   == 4)
               app.Button_8.Icon   = '4.jpeg';
               app.but8 = '4.jpeg';
           
           elseif (app.c(8)   == 5)
               app.Button_8.Icon   = '5.jpeg';
               app.but8 = '5.jpeg';
               
           elseif (app.c(8)   == 5)
               app.Button_8.Icon   = '5.jpeg';
               app.but8 = '5.jpeg';
           
           elseif (app.c(8)   == 6)
               app.Button_8.Icon   = '6.jpeg';
               app.but8 = '6.jpeg';
               
           elseif (app.c(8)   == 7)
               app.Button_8.Icon   = '7.jpeg';
               app.but8 = '7.jpeg';
           
           elseif (app.c(8)   == 8)
               app.Button_8.Icon   = '8.jpeg';
               app.but8 = '8.jpeg';
               
           elseif (app.c(8)   == 9)
               app.Button_8.Icon   = '9.jpeg';
               app.but8 = '9.jpeg';
           
           else 
               app.Button_8.Icon   = '10.jpeg';
               app.but8 = '10.jpeg';
           end
           
           %Boton 9
           if(app.c(9)   == 1)
               app.Button_9.Icon    = '1.jpeg';
               app.but9 = '1.jpeg';
           
           elseif (app.c(9)    == 2)
               app.Button_9.Icon    = '2.jpeg';
               app.but9 = '2.jpeg';
               
           elseif (app.c(9)    == 3)
               app.Button_9.Icon    = '3.jpeg';
               app.but9 = '3.jpeg';
           
           elseif (app.c(9)    == 4)
               app.Button_9.Icon    = '4.jpeg';
               app.but9 = '4.jpeg';
           
           elseif (app.c(9)    == 5)
               app.Button_9.Icon    = '5.jpeg';
               app.but9 = '5.jpeg';
               
           elseif (app.c(9)    == 5)
               app.Button_9.Icon    = '5.jpeg';
               app.but9 = '5.jpeg';
           
           elseif (app.c(9)    == 6)
               app.Button_9.Icon    = '6.jpeg';
               app.but9 = '6.jpeg';
               
           elseif (app.c(9)    == 7)
               app.Button_9.Icon    = '7.jpeg';
               app.but9 = '7.jpeg';
           
           elseif (app.c(9)    == 8)
               app.Button_9.Icon    = '8.jpeg';
               app.but9 = '8.jpeg';
               
           elseif (app.c(9)    == 9)
               app.Button_9.Icon    = '9.jpeg';
               app.but9 = '9.jpeg';
           
           else 
               app.Button_9.Icon    = '10.jpeg';
               app.but9 = '10.jpeg';
           end
           
           %Boton 10
           if(app.c(10)   == 1)
               app.Button_10.Icon    = '1.jpeg';
               app.but10 = '1.jpeg';
           
           elseif (app.c(10)    == 2)
               app.Button_10.Icon    = '2.jpeg';
               app.but10 = '2.jpeg';
               
           elseif (app.c(10)    == 3)
               app.Button_10.Icon    = '3.jpeg';
               app.but10 = '3.jpeg';
           
           elseif (app.c(10)    == 4)
               app.Button_10.Icon    = '4.jpeg';
               app.but10 = '4.jpeg';
           
           elseif (app.c(10)    == 5)
               app.Button_10.Icon    = '5.jpeg';
               app.but10 = '5.jpeg';
               
           elseif (app.c(10)    == 5)
               app.Button_10.Icon    = '5.jpeg';
               app.but10 = '5.jpeg';
           
           elseif (app.c(10)    == 6)
               app.Button_10.Icon    = '6.jpeg';
               app.but10 = '6.jpeg';
               
           elseif (app.c(10)    == 7)
               app.Button_10.Icon    = '7.jpeg';
               app.but10 = '7.jpeg';
           
           elseif (app.c(10)    == 8)
               app.Button_10.Icon    = '8.jpeg';
               app.but10 = '8.jpeg';
               
           elseif (app.c(10)    == 9)
               app.Button_10.Icon    = '9.jpeg';
               app.but10 = '9.jpeg';
           
           else 
               app.Button_10.Icon    = '10.jpeg';
               app.but10 = '10.jpeg';
           end
           
           %Boton 11
           if(app.c(11)    == 1)
               app.Button_11.Icon     = '1.jpeg';
               app.but11 = '1.jpeg';
           
           elseif (app.c(11)     == 2)
               app.Button_11.Icon     = '2.jpeg';
               app.but11 = '2.jpeg';
               
           elseif (app.c(11)     == 3)
               app.Button_11.Icon     = '3.jpeg';
               app.but11 = '3.jpeg';
           
           elseif (app.c(11)     == 4)
               app.Button_11.Icon     = '4.jpeg';
               app.but11 = '4.jpeg';
           
           elseif (app.c(11)     == 5)
               app.Button_11.Icon     = '5.jpeg';
               app.but11 = '5.jpeg';
               
           elseif (app.c(11)     == 5)
               app.Button_11.Icon     = '5.jpeg';
               app.but11 = '5.jpeg';
           
           elseif (app.c(11)     == 6)
               app.Button_11.Icon     = '6.jpeg';
               app.but11 = '6.jpeg';
               
           elseif (app.c(11)     == 7)
               app.Button_11.Icon     = '7.jpeg';
               app.but11 = '7.jpeg';
           
           elseif (app.c(11)     == 8)
               app.Button_11.Icon     = '8.jpeg';
               app.but11 = '8.jpeg';
               
           elseif (app.c(11)     == 9)
               app.Button_11.Icon     = '9.jpeg';
               app.but11 = '9.jpeg';
           
           else 
               app.Button_11.Icon     = '10.jpeg';
               app.but11 = '10.jpeg';
           end
           
           %Boton 12
           if(app.c(12)    == 1)
               app.Button_12.Icon = '1.jpeg';
               app.but12 = '1.jpeg';
           
           elseif (app.c(12)     == 2)
               app.Button_12.Icon     = '2.jpeg';
               app.but12 = '2.jpeg';
               
           elseif (app.c(12)     == 3)
               app.Button_12.Icon     = '3.jpeg';
               app.but12 = '3.jpeg';
           
           elseif (app.c(12)     == 4)
               app.Button_12.Icon     = '4.jpeg';
               app.but12 = '4.jpeg';
           
           elseif (app.c(12)     == 5)
               app.Button_12.Icon     = '5.jpeg';
               app.but12 = '5.jpeg';
               
           elseif (app.c(12)     == 5)
               app.Button_12.Icon     = '5.jpeg';
               app.but12 = '5.jpeg';
           
           elseif (app.c(12)     == 6)
               app.Button_12.Icon     = '6.jpeg';
               app.but12 = '6.jpeg';
               
           elseif (app.c(12)     == 7)
               app.Button_12.Icon     = '7.jpeg';
               app.but12 = '7.jpeg';
           
           elseif (app.c(12)     == 8)
               app.Button_12.Icon     = '8.jpeg';
               app.but12 = '8.jpeg';
               
           elseif (app.c(12)     == 9)
               app.Button_12.Icon     = '9.jpeg';
               app.but12 = '9.jpeg';
           
           else 
               app.Button_12.Icon     = '10.jpeg';
               app.but12 = '10.jpeg';
           end
           
           %Boton 13
           if(app.c(13)    == 1)
               app.Button_13.Icon = '1.jpeg';
               app.but13 = '1.jpeg';
           
           elseif (app.c(13)     == 2)
               app.Button_13.Icon     = '2.jpeg';
               app.but13 = '2.jpeg';
               
           elseif (app.c(13)     == 3)
               app.Button_13.Icon     = '3.jpeg';
               app.but13 = '3.jpeg';
           
           elseif (app.c(13)     == 4)
               app.Button_13.Icon     = '4.jpeg';
               app.but13 = '4.jpeg';
           
           elseif (app.c(13)     == 5)
               app.Button_13.Icon     = '5.jpeg';
               app.but13 = '5.jpeg';
               
           elseif (app.c(13)     == 5)
               app.Button_13.Icon     = '5.jpeg';
               app.but13 = '5.jpeg';
           
           elseif (app.c(13)     == 6)
               app.Button_13.Icon     = '6.jpeg';
               app.but13 = '6.jpeg';
               
           elseif (app.c(13)     == 7)
               app.Button_13.Icon     = '7.jpeg';
               app.but13 = '7.jpeg';
           
           elseif (app.c(13)     == 8)
               app.Button_13.Icon     = '8.jpeg';
               app.but13 = '8.jpeg';
               
           elseif (app.c(13)     == 9)
               app.Button_13.Icon     = '9.jpeg';
               app.but13 = '9.jpeg';
           
           else 
               app.Button_13.Icon     = '10.jpeg';
               app.but13 = '10.jpeg';
           end
           
           %Boton 14
           if(app.c(14)    == 1)
               app.Button_14.Icon = '1.jpeg';
               app.but14 = '1.jpeg';
           
           elseif (app.c(14)     == 2)
               app.Button_14.Icon     = '2.jpeg';
               app.but14 = '2.jpeg';
               
           elseif (app.c(14)     == 3)
               app.Button_14.Icon     = '3.jpeg';
               app.but14 = '3.jpeg';
           
           elseif (app.c(14)     == 4)
               app.Button_14.Icon     = '4.jpeg';
               app.but14 = '4.jpeg';
           
           elseif (app.c(14)     == 5)
               app.Button_14.Icon     = '5.jpeg';
               app.but14 = '5.jpeg';
               
           elseif (app.c(14)     == 5)
               app.Button_14.Icon     = '5.jpeg';
               app.but14 = '5.jpeg';
           
           elseif (app.c(14)     == 6)
               app.Button_14.Icon     = '6.jpeg';
               app.but14 = '6.jpeg';
               
           elseif (app.c(14)     == 7)
               app.Button_14.Icon     = '7.jpeg';
               app.but14 = '7.jpeg';
           
           elseif (app.c(14)     == 8)
               app.Button_14.Icon     = '8.jpeg';
               app.but14 = '8.jpeg';
               
           elseif (app.c(14)     == 9)
               app.Button_14.Icon     = '9.jpeg';
               app.but14 = '9.jpeg';
           
           else 
               app.Button_14.Icon     = '10.jpeg';
               app.but14 = '10.jpeg';
           end
           
           %Boton 15
           if(app.c(15)    == 1)
               app.Button_15.Icon = '1.jpeg';
               app.but15 = '1.jpeg';
           
           elseif (app.c(15)     == 2)
               app.Button_15.Icon     = '2.jpeg';
               app.but15 = '2.jpeg';
               
           elseif (app.c(15)     == 3)
               app.Button_15.Icon     = '3.jpeg';
               app.but15 = '3.jpeg';
           
           elseif (app.c(15)     == 4)
               app.Button_15.Icon     = '4.jpeg';
               app.but15 = '4.jpeg';
           
           elseif (app.c(15)     == 5)
               app.Button_15.Icon     = '5.jpeg';
               app.but15 = '5.jpeg';
               
           elseif (app.c(15)     == 5)
               app.Button_15.Icon     = '5.jpeg';
               app.but15 = '5.jpeg';
           
           elseif (app.c(15)     == 6)
               app.Button_15.Icon     = '6.jpeg';
               app.but15 = '6.jpeg';
               
           elseif (app.c(15)     == 7)
               app.Button_15.Icon     = '7.jpeg';
               app.but15 = '7.jpeg';
           
           elseif (app.c(15)     == 8)
               app.Button_15.Icon     = '8.jpeg';
               app.but15 = '8.jpeg';
               
           elseif (app.c(15)     == 9)
               app.Button_15.Icon     = '9.jpeg';
               app.but15 = '9.jpeg';
           
           else 
               app.Button_15.Icon     = '10.jpeg';
               app.but15 = '10.jpeg';
           end
           
           %Boton 16
           if(app.c(16)    == 1)
               app.Button_16.Icon = '1.jpeg';
               app.but16 = '1.jpeg';
           
           elseif (app.c(16)     == 2)
               app.Button_16.Icon     = '2.jpeg';
               app.but16 = '2.jpeg';
               
           elseif (app.c(16)     == 3)
               app.Button_16.Icon     = '3.jpeg';
               app.but16 = '3.jpeg';
           
           elseif (app.c(16)     == 4)
               app.Button_16.Icon     = '4.jpeg';
               app.but16 = '4.jpeg';
           
           elseif (app.c(16)     == 5)
               app.Button_16.Icon     = '5.jpeg';
               app.but16 = '5.jpeg';
               
           elseif (app.c(16)     == 5)
               app.Button_16.Icon     = '5.jpeg';
               app.but16 = '5.jpeg';
           
           elseif (app.c(16)     == 6)
               app.Button_16.Icon     = '6.jpeg';
               app.but16 = '6.jpeg';
               
           elseif (app.c(16)     == 7)
               app.Button_16.Icon     = '7.jpeg';
               app.but16 = '7.jpeg';
           
           elseif (app.c(16)     == 8)
               app.Button_16.Icon     = '8.jpeg';
               app.but16 = '8.jpeg';
               
           elseif (app.c(16)     == 9)
               app.Button_16.Icon     = '9.jpeg';
               app.but16 = '9.jpeg';
           
           else 
               app.Button_16.Icon     = '10.jpeg';
               app.but16 = '10.jpeg';
           end
           
           %Boton 17
           if(app.c(17)    == 1)
               app.Button_17.Icon = '1.jpeg';
               app.but17 = '1.jpeg';
           
           elseif (app.c(17)     == 2)
               app.Button_17.Icon     = '2.jpeg';
               app.but17 = '2.jpeg';
               
           elseif (app.c(17)     == 3)
               app.Button_17.Icon     = '3.jpeg';
               app.but17 = '3.jpeg';
           
           elseif (app.c(17)     == 4)
               app.Button_17.Icon     = '4.jpeg';
               app.but17 = '4.jpeg';
           
           elseif (app.c(17)     == 5)
               app.Button_17.Icon     = '5.jpeg';
               app.but17 = '5.jpeg';
               
           elseif (app.c(17)     == 5)
               app.Button_17.Icon     = '5.jpeg';
               app.but17 = '5.jpeg';
           
           elseif (app.c(17)     == 6)
               app.Button_17.Icon     = '6.jpeg';
               app.but17 = '6.jpeg';
               
           elseif (app.c(17)     == 7)
               app.Button_17.Icon     = '7.jpeg';
               app.but17 = '7.jpeg';
           
           elseif (app.c(17)     == 8)
               app.Button_17.Icon     = '8.jpeg';
               app.but17 = '8.jpeg';
               
           elseif (app.c(17)     == 9)
               app.Button_17.Icon     = '9.jpeg';
               app.but17 = '9.jpeg';
           
           else 
               app.Button_17.Icon     = '10.jpeg';
               app.but17 = '10.jpeg';
           end
           
           %Boton 18
           if(app.c(18)    == 1)
               app.Button_18.Icon = '1.jpeg';
               app.but18 = '1.jpeg';
           
           elseif (app.c(18)     == 2)
               app.Button_18.Icon     = '2.jpeg';
               app.but18 = '2.jpeg';
               
           elseif (app.c(18)     == 3)
               app.Button_18.Icon     = '3.jpeg';
               app.but18 = '3.jpeg';
           
           elseif (app.c(18)     == 4)
               app.Button_18.Icon     = '4.jpeg';
               app.but18 = '4.jpeg';
           
           elseif (app.c(18)     == 5)
               app.Button_18.Icon     = '5.jpeg';
               app.but18 = '5.jpeg';
               
           elseif (app.c(18)     == 5)
               app.Button_18.Icon     = '5.jpeg';
               app.but18 = '5.jpeg';
           
           elseif (app.c(18)     == 6)
               app.Button_18.Icon     = '6.jpeg';
               app.but18 = '6.jpeg';
               
           elseif (app.c(18)     == 7)
               app.Button_18.Icon     = '7.jpeg';
               app.but18 = '7.jpeg';
           
           elseif (app.c(18)     == 8)
               app.Button_18.Icon     = '8.jpeg';
               app.but18 = '8.jpeg';
               
           elseif (app.c(18)     == 9)
               app.Button_18.Icon     = '9.jpeg';
               app.but18 = '9.jpeg';
           
           else 
               app.Button_18.Icon     = '10.jpeg';
               app.but18 = '10.jpeg';
           end
           
           %Boton 19
           if(app.c(19)    == 1)
               app.Button_19.Icon = '1.jpeg';
               app.but19 = '1.jpeg';
           
           elseif (app.c(19)     == 2)
               app.Button_19.Icon     = '2.jpeg';
               app.but19 = '2.jpeg';
               
           elseif (app.c(19)     == 3)
               app.Button_19.Icon     = '3.jpeg';
               app.but19 = '3.jpeg';
           
           elseif (app.c(19)     == 4)
               app.Button_19.Icon     = '4.jpeg';
               app.but19 = '4.jpeg';
           
           elseif (app.c(19)     == 5)
               app.Button_19.Icon     = '5.jpeg';
               app.but19 = '5.jpeg';
               
           elseif (app.c(19)     == 5)
               app.Button_19.Icon     = '5.jpeg';
               app.but19 = '5.jpeg';
           
           elseif (app.c(19)     == 6)
               app.Button_19.Icon     = '6.jpeg';
               app.but19 = '6.jpeg';
               
           elseif (app.c(19)     == 7)
               app.Button_19.Icon     = '7.jpeg';
               app.but19 = '7.jpeg';
           
           elseif (app.c(19)     == 8)
               app.Button_19.Icon     = '8.jpeg';
               app.but19 = '8.jpeg';
               
           elseif (app.c(19)     == 9)
               app.Button_19.Icon     = '9.jpeg';
               app.but19 = '9.jpeg';
           
           else 
               app.Button_19.Icon     = '10.jpeg';
               app.but19 = '10.jpeg';
           end
           
           %Boton 20
           if(app.c(20)    == 1)
               app.Button_20.Icon = '1.jpeg';
               app.but20 = '1.jpeg';
           
           elseif (app.c(20)     == 2)
               app.Button_20.Icon     = '2.jpeg';
               app.but20 = '2.jpeg';
               
           elseif (app.c(20)     == 3)
               app.Button_20.Icon     = '3.jpeg';
               app.but20 = '3.jpeg';
           
           elseif (app.c(20)     == 4)
               app.Button_20.Icon     = '4.jpeg';
               app.but20 = '4.jpeg';
           
           elseif (app.c(20)     == 5)
               app.Button_20.Icon     = '5.jpeg';
               app.but20 = '5.jpeg';
               
           elseif (app.c(20)     == 5)
               app.Button_20.Icon     = '5.jpeg';
               app.but20 = '5.jpeg';
           
           elseif (app.c(20)     == 6)
               app.Button_20.Icon     = '6.jpeg';
               app.but20 = '6.jpeg';
               
           elseif (app.c(20)     == 7)
               app.Button_20.Icon     = '7.jpeg';
               app.but20 = '7.jpeg';
           
           elseif (app.c(20)     == 8)
               app.Button_20.Icon     = '8.jpeg';
               app.but20 = '8.jpeg';
               
           elseif (app.c(20)     == 9)
               app.Button_20.Icon     = '9.jpeg';
               app.but20 = '9.jpeg';
           
           else 
               app.Button_20.Icon     = '10.jpeg';
               app.but20 = '10.jpeg';
           end
           
           pause(3);
           
           %Boton 1
           app.Button.Icon = ''; 
           app.Button.Enable = 'on';
           
           %Boton 2
           app.Button_2.Icon = ''; 
           app.Button_2.Enable = 'on';
           
           %Boton 3
           app.Button_3.Icon = ''; 
           app.Button_3.Enable = 'on';
           
           %Boton 4
           app.Button_4.Icon = ''; 
           app.Button_4.Enable = 'on';
           
           %Boton 5
           app.Button_5.Icon = ''; 
           app.Button_5.Enable = 'on';
           
           %Boton 6
           app.Button_6.Icon = ''; 
           app.Button_6.Enable = 'on';
           
           %Boton 7
           app.Button_7.Icon = ''; 
           app.Button_7.Enable = 'on';
           
           %Boton 8
           app.Button_8.Icon = ''; 
           app.Button_8.Enable = 'on';
           
           %Boton 9
           app.Button_9.Icon = ''; 
           app.Button_9.Enable = 'on';
           
           %Boton 10
           app.Button_10.Icon = ''; 
           app.Button_10.Enable = 'on';
           
           %Boton 11
           app.Button_11.Icon = ''; 
           app.Button_11.Enable = 'on';
           
           %Boton 12
           app.Button_12.Icon = ''; 
           app.Button_12.Enable = 'on';
           
           %Boton 13
           app.Button_13.Icon = ''; 
           app.Button_13.Enable = 'on';
           
           %Boton 14
           app.Button_14.Icon = ''; 
           app.Button_14.Enable = 'on';
           
           %Boton 15
           app.Button_15.Icon = ''; 
           app.Button_15.Enable = 'on';
           
           %Boton 16
           app.Button_16.Icon = ''; 
           app.Button_16.Enable = 'on';
           
           %Boton 17
           app.Button_17.Icon = ''; 
           app.Button_17.Enable = 'on';
           
           %Boton 18
           app.Button_18.Icon = ''; 
           app.Button_18.Enable = 'on';
           
           %Boton 19
           app.Button_19.Icon = ''; 
           app.Button_19.Enable = 'on';
           
           %Boton 20
           app.Button_20.Icon = ''; 
           app.Button_20.Enable = 'on';
           
           app.VamoadarleButton.Text = 'Restart';
           
           
           %Asiganacion de jugadores
           app.Jugador1.Value = 0;
           app.Jugador2.Value = 0;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            if (app.j ~= 1) 
                app.Button.Icon = app.but1;
                app.count = app.count + 1;
            
                if (app.count == 2)
                    
                    if (checkImgs(app, app.c(1), app.i))
                        app.Button.Enable = 'off';
                        func2(app);
                        app.count = 0;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            app.score1 = app.score1 + 10;
                            app.bandera = 0;


                        elseif app.bandera == 1
                            app.score2 = app.score2 + 10;
                            app.bandera = 1;
                            
                            
                        end
                        
                    else
                        func3(app);
                        app.i = app.c(1);
                        app.j = 1;
                        
                        app.count = app.count - 1;
                        
                        
                        %asignación de turnos
                        if app.bandera == 0
                            
                            if app.score1 > 0
                                app.score1 = app.score1 - 2;
                            end
                            
                            
                            app.bandera = 1;
                            app.mensaje = msgbox('Es turno del Jugador 2');
                            
                        elseif app.bandera == 1
                            
                            if app.score2 > 0
                                app.score2 = app.score2 - 2;
                            end
                            
                            
                            app.bandera = 0;
                            app.mensaje = msgbox('Es turno del Jugador 1');
                        end
                        
                    end
                else
                    app.i = app.c(1);
                    app.j = 1;
                end
            end
            
            result1(app);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            
            %**--COMPARACIÓN PAPITO--**%
            if (app.j ~= 2)
                app.Button_2.Icon = app.but2;
                app.count = app.count + 1;
                
                if (app.count == 2)

                    if (checkImgs(app, app.c(2), app.i))
                        
                        app.Button_2.Enable = 'off';
                        func2(app);
                        app.count = 0;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            app.score1 = app.score1 + 10;
                            app.bandera = 0;
                            
                        elseif app.bandera == 1
                            app.score2 = app.score2 + 10;
                            app.bandera = 1;
                            
                        end
                        
                    else
                        func3(app);
                        app.i = app.c(2);
                        app.j = 2;
                        
                        app.count = app.count - 1;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            
                            if app.score1 > 0
                                app.score1 = app.score1 - 2;
                            end
                            
                            app.bandera = 1;
                            app.mensaje = msgbox('Es turno del Jugador 2');
                            
                        elseif app.bandera == 1
                            
                            if app.score2 > 0
                                app.score2 = app.score2 - 2;
                            end
                            
                            
                            app.bandera = 0;
                            app.mensaje = msgbox('Es turno del Jugador 1');
                        end
                        
                    end
                else
                    app.i = app.c(2);
                    app.j = 2;
                    
                end
            end
            
            result1(app);


        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)

            if (app.j ~= 3) 
                app.Button_3.Icon = app.but3;
                app.count = app.count + 1;
                
                if (app.count == 2)
                    
                    if (checkImgs(app, app.c(3), app.i))
                        app.Button_3.Enable = 'off';
                        func2(app);
                        app.count = 0;

                        %asignación de turnos
                        if app.bandera == 0
                            app.score1 = app.score1 + 10;
                            app.bandera = 0;
                        elseif app.bandera == 1
                            app.score2 = app.score2 + 10;
                            app.bandera = 1;
                        end
                    else
                        func3(app);
                        app.i = app.c(3);
                        app.j = 3;
                        
                        app.count = app.count - 1;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            
                            if app.score1 > 0
                                app.score1 = app.score1 - 2;
                            end
                            
                            app.bandera = 1;
                            app.mensaje = msgbox('Es turno del Jugador 2');
                            
                        elseif app.bandera == 1
                            
                            if app.score2 > 0
                                app.score2 = app.score2 - 2;
                            end
                            
                            
                            app.bandera = 0;
                            app.mensaje = msgbox('Es turno del Jugador 1');
                        end
                        
                    end
                else
                    app.i = app.c(3);
                    app.j = 3;
                   
                end
            end
            
            result1(app);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if (app.j ~= 4) 
                app.Button_4.Icon = app.but4;
                app.count = app.count + 1;
                
                if (app.count == 2)
                    
                    if (checkImgs(app, app.c(4), app.i))
                        app.Button_4.Enable = 'off';
                        func2(app);
                        app.count = 0;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            app.score1 = app.score1 + 10;
                            app.bandera = 0;
                        elseif app.bandera == 1
                            app.score2 = app.score2 + 10;
                            app.bandera = 1;
                        end
                        
                    else
                        
                        func3(app);
                        app.i = app.c(4);
                        app.j = 4;
                        
                        app.count = app.count - 1;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            
                            if app.score1 > 0
                                app.score1 = app.score1 - 2;
                            end
                            
                            app.bandera = 1;
                            app.mensaje = msgbox('Es turno del Jugador 2');
                            
                        elseif app.bandera == 1
                            
                            if app.score2 > 0
                                app.score2 = app.score2 - 2;
                            end
                            
                            
                            app.bandera = 0;
                            app.mensaje = msgbox('Es turno del Jugador 1');
                        end                       
                    end
                else
                    app.i = app.c(4);
                    app.j = 4;
                    
                end
            end
            
            result1(app);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            if (app.j ~= 5) 
                app.Button_5.Icon = app.but5;
                app.count = app.count + 1;
                
                if (app.count == 2)
                    
                    if (checkImgs(app, app.c(5), app.i))
                        app.Button_5.Enable = 'off';
                        func2(app);
                        app.count = 0;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            app.score1 = app.score1 + 10;
                            app.bandera = 0;
                        elseif app.bandera == 1
                            app.score2 = app.score2 + 10;
                            app.bandera = 1;
                        end
                        
                    else
                        
                        func3(app);
                        app.i = app.c(5);
                        app.j = 5;
                        
                        app.count = app.count - 1;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            
                            if app.score1 > 0
                                app.score1 = app.score1 - 2;
                            end
                            
                            app.bandera = 1;
                            app.mensaje = msgbox('Es turno del Jugador 2');
                            
                        elseif app.bandera == 1
                            
                            if app.score2 > 0
                                app.score2 = app.score2 - 2;
                            end
                            
                            
                            app.bandera = 0;
                            app.mensaje = msgbox('Es turno del Jugador 1');
                        end
                        
                    end
                else
                    app.i = app.c(5);
                    app.j = 5;
                end
            end
            
            result1(app);
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            if (app.j ~= 7) 
                app.Button_7.Icon = app.but7;
                app.count = app.count + 1;
                
                if (app.count == 2)
                    
                    if (checkImgs(app, app.c(7), app.i))
                        app.Button_7.Enable = 'off';
                        func2(app);
                        app.count = 0;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            app.score1 = app.score1 + 10;
                            app.bandera = 0;
                        elseif app.bandera == 1
                            app.score2 = app.score2 + 10;
                            app.bandera = 1;
                        end
                        
                    else
                        func3(app);
                        app.i = app.c(7);
                        app.j = 7;
                        app.count = app.count - 1;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            
                            if app.score1 > 0
                                app.score1 = app.score1 - 2;
                            end
                            
                            app.bandera = 1;
                            app.mensaje = msgbox('Es turno del Jugador 2');
                            
                        elseif app.bandera == 1
                            
                            if app.score2 > 0
                                app.score2 = app.score2 - 2;
                            end
                            
                            
                            app.bandera = 0;
                            app.mensaje = msgbox('Es turno del Jugador 1');
                        end
                    end
                else
                    app.i = app.c(7);
                    app.j = 7;
                end
            end
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            if (app.j ~= 8) 
                app.Button_8.Icon = app.but8;
                app.count = app.count + 1;
                            
                if (app.count == 2)
                    if (checkImgs(app, app.c(8), app.i))
                        app.Button_8.Enable = 'off';
                        func2(app);
                        app.count = 0;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            app.score1 = app.score1 + 10;
                            app.bandera = 0;
                        elseif app.bandera == 1
                            app.score2 = app.score2 + 10;
                            app.bandera = 1;
                        end
                        
                    else
                        func3(app);
                        app.i = app.c(8);
                        app.j = 8;
                        app.count = app.count - 1;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            
                            if app.score1 > 0
                                app.score1 = app.score1 - 2;
                            end
                            
                            app.bandera = 1;
                            app.mensaje = msgbox('Es turno del Jugador 2');
                            
                        elseif app.bandera == 1
                            
                            if app.score2 > 0
                                app.score2 = app.score2 - 2;
                            end
                            
                            
                            app.bandera = 0;
                            app.mensaje = msgbox('Es turno del Jugador 1');
                        end
                    end
                else
                    app.i = app.c(8);
                    app.j = 8;
                end
           end
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            if (app.j ~= 13) 
                app.Button_13.Icon = app.but13;
                app.count = app.count + 1;
                            
                if (app.count == 2)
                    if (checkImgs(app, app.c(13), app.i))
                        app.Button_13.Enable = 'off';
                        func2(app);
                        app.count = 0;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            app.score1 = app.score1 + 10;
                            app.bandera = 0;
                        elseif app.bandera == 1
                            app.score2 = app.score2 + 10;
                            app.bandera = 1;
                        end
                    else
                        func3(app);
                        app.i = app.c(13);
                        app.j = 13;
                        app.count = app.count - 1;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            
                            if app.score1 > 0
                                app.score1 = app.score1 - 2;
                            end
                            
                            app.bandera = 1;
                            app.mensaje = msgbox('Es turno del Jugador 2');
                            
                        elseif app.bandera == 1
                            
                            if app.score2 > 0
                                app.score2 = app.score2 - 2;
                            end
                            
                            
                            app.bandera = 0;
                            app.mensaje = msgbox('Es turno del Jugador 1');
                        end
                    end
                else
                    app.i = app.c(13);
                    app.j = 13;
                end
            end
        end

        % Button pushed function: Button_20
        function Button_20Pushed(app, event)
            if (app.j ~= 20) 
                app.Button_20.Icon = app.but20;
                app.count = app.count + 1;
                            
                if (app.count == 2)
                    if (checkImgs(app, app.c(20), app.i))
                        app.Button_20.Enable = 'off';
                        func2(app);
                        app.count = 0;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            app.score1 = app.score1 + 10;
                            app.bandera = 0;
                        elseif app.bandera == 1
                            app.score2 = app.score2 + 10;
                            app.bandera = 1;
                        end
                    else
                        func3(app);
                        app.i = app.c(20);
                        app.j = 20;
                        app.count = app.count - 1;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            
                            if app.score1 > 0
                                app.score1 = app.score1 - 2;
                            end
                            
                            app.bandera = 1;
                            app.mensaje = msgbox('Es turno del Jugador 2');
                            
                        elseif app.bandera == 1
                            
                            if app.score2 > 0
                                app.score2 = app.score2 - 2;
                            end
                            
                            
                            app.bandera = 0;
                            app.mensaje = msgbox('Es turno del Jugador 1');
                        end
                    end
                else
                    app.i = app.c(20);
                    app.j = 20;
                end
            end
        end

        % Button pushed function: Button_19
        function Button_19Pushed(app, event)
            if (app.j ~= 19) 
                app.Button_19.Icon = app.but19;
                app.count = app.count + 1;
                            
                if (app.count == 2)
                    if (checkImgs(app, app.c(19), app.i))
                        app.Button_19.Enable = 'off';
                        func2(app);
                        app.count = 0;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            app.score1 = app.score1 + 10;
                            app.bandera = 0;
                        elseif app.bandera == 1
                            app.score2 = app.score2 + 10;
                            app.bandera = 1;
                        end
                    else
                        func3(app);
                        app.i = app.c(19);
                        app.j = 19;
                        app.count = app.count - 1;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            
                            if app.score1 > 0
                                app.score1 = app.score1 - 2;
                            end
                            
                            app.bandera = 1;
                            app.mensaje = msgbox('Es turno del Jugador 2');
                            
                        elseif app.bandera == 1
                            
                            if app.score2 > 0
                                app.score2 = app.score2 - 2;
                            end
                            
                            
                            app.bandera = 0;
                            app.mensaje = msgbox('Es turno del Jugador 1');
                        end
                    end
                else
                    app.i = app.c(19);
                    app.j = 19;
                end
            end
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            if (app.j ~= 9) 
                app.Button_9.Icon = app.but9;
                app.count = app.count + 1;
                            
                if (app.count == 2)
                    if (checkImgs(app, app.c(9), app.i))
                        app.Button_9.Enable = 'off';
                        func2(app);
                        app.count = 0;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            app.score1 = app.score1 + 10;
                            app.bandera = 0;
                        elseif app.bandera == 1
                            app.score2 = app.score2 + 10;
                            app.bandera = 1;
                        end
                    else
                        func3(app);
                        app.i = app.c(9);
                        app.j = 9;
                        app.count = app.count - 1;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            
                            if app.score1 > 0
                                app.score1 = app.score1 - 2;
                            end
                            
                            app.bandera = 1;
                            app.mensaje = msgbox('Es turno del Jugador 2');
                            
                        elseif app.bandera == 1
                            
                            if app.score2 > 0
                                app.score2 = app.score2 - 2;
                            end
                            
                            
                            app.bandera = 0;
                            app.mensaje = msgbox('Es turno del Jugador 1');
                        end
                    end
                else
                    app.i = app.c(9);
                    app.j = 9;
                end
            end
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            if (app.j ~= 11) 
                app.Button_11.Icon = app.but11;
                app.count = app.count + 1;
                            
                if (app.count == 2)
                    if (checkImgs(app, app.c(11), app.i))
                        app.Button_11.Enable = 'off';
                        func2(app);
                        app.count = 0;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            app.score1 = app.score1 + 10;
                            app.bandera = 0;
                        elseif app.bandera == 1
                            app.score2 = app.score2 + 10;
                            app.bandera = 1;
                        end
                        
                    else
                        func3(app);
                        app.i = app.c(11);
                        app.j = 11;
                        app.count = app.count - 1;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            
                            if app.score1 > 0
                                app.score1 = app.score1 - 2;
                            end
                            
                            app.bandera = 1;
                            app.mensaje = msgbox('Es turno del Jugador 2');
                            
                        elseif app.bandera == 1
                            
                            if app.score2 > 0
                                app.score2 = app.score2 - 2;
                            end
                            
                            
                            app.bandera = 0;
                            app.mensaje = msgbox('Es turno del Jugador 1');
                        end
                    end
                else
                    app.i = app.c(11);
                    app.j = 11;
                end
            end
        end

        % Button pushed function: Button_17
        function Button_17Pushed(app, event)
            if (app.j ~= 17) 
                app.Button_17.Icon = app.but17;
                app.count = app.count + 1;
                            
                if (app.count == 2)
                    if (checkImgs(app, app.c(17), app.i))
                        app.Button_17.Enable = 'off';
                        func2(app);
                        app.count = 0;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            app.score1 = app.score1 + 10;
                            app.bandera = 0;
                        elseif app.bandera == 1
                            app.score2 = app.score2 + 10;
                            app.bandera = 1;
                        end
                    else
                        func3(app);
                        app.i = app.c(17);
                        app.j = 17;
                        app.count = app.count - 1;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            
                            if app.score1 > 0
                                app.score1 = app.score1 - 2;
                            end
                            
                            app.bandera = 1;
                            app.mensaje = msgbox('Es turno del Jugador 2');
                            
                        elseif app.bandera == 1
                            
                            if app.score2 > 0
                                app.score2 = app.score2 - 2;
                            end
                            
                            
                            app.bandera = 0;
                            app.mensaje = msgbox('Es turno del Jugador 1');
                        end
                    end
                else
                    app.i = app.c(17);
                    app.j = 17;
                end
            end
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            if (app.j ~= 16) 
                app.Button_16.Icon = app.but16;
                app.count = app.count + 1;
                            
                if (app.count == 2)
                    if (checkImgs(app, app.c(16), app.i))
                        app.Button_16.Enable = 'off';
                        func2(app);
                        app.count = 0;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            app.score1 = app.score1 + 10;
                            app.bandera = 0;
                        elseif app.bandera == 1
                            app.score2 = app.score2 + 10;
                            app.bandera = 1;
                        end
                        
                    else
                        func3(app);
                        app.i = app.c(16);
                        app.j = 16;
                        app.count = app.count - 1;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            
                            if app.score1 > 0
                                app.score1 = app.score1 - 2;
                            end
                            
                            app.bandera = 1;
                            app.mensaje = msgbox('Es turno del Jugador 2');
                            
                        elseif app.bandera == 1
                            
                            if app.score2 > 0
                                app.score2 = app.score2 - 2;
                            end
                            
                            
                            app.bandera = 0;
                            app.mensaje = msgbox('Es turno del Jugador 1');
                        end
                    end
                else
                    app.i = app.c(16);
                    app.j = 16;
                end
            end
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            if (app.j ~= 15) 
                app.Button_15.Icon = app.but15;
                app.count = app.count + 1;
                            
                if (app.count == 2)
                    if (checkImgs(app, app.c(15), app.i))
                        app.Button_15.Enable = 'off';
                        func2(app);
                        app.count = 0;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            app.score1 = app.score1 + 10;
                            app.bandera = 0;
                        elseif app.bandera == 1
                            app.score2 = app.score2 + 10;
                            app.bandera = 1;
                        end
                        
                    else
                        func3(app);
                        app.i = app.c(15);
                        app.j = 15;
                        app.count = app.count - 1;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            
                            if app.score1 > 0
                                app.score1 = app.score1 - 2;
                            end
                            
                            app.bandera = 1;
                            app.mensaje = msgbox('Es turno del Jugador 2');
                            
                        elseif app.bandera == 1
                            
                            if app.score2 > 0
                                app.score2 = app.score2 - 2;
                            end
                            
                            
                            app.bandera = 0;
                            app.mensaje = msgbox('Es turno del Jugador 1');
                        end
                    end
                else
                    app.i = app.c(15);
                    app.j = 15;
                end
            end
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            if (app.j ~= 10) 
                app.Button_10.Icon = app.but10;
                app.count = app.count + 1;
                            
                if (app.count == 2)
                    if (checkImgs(app, app.c(10), app.i))
                        app.Button_10.Enable = 'off';
                        func2(app);
                        app.count = 0;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            app.score1 = app.score1 + 10;
                            app.bandera = 0;
                        elseif app.bandera == 1
                            app.score2 = app.score2 + 10;
                            app.bandera = 1;
                        end
                        
                    else
                        func3(app);
                        app.i = app.c(10);
                        app.j = 10;
                        app.count = app.count - 1;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            
                            if app.score1 > 0
                                app.score1 = app.score1 - 2;
                            end
                            
                            app.bandera = 1;
                            app.mensaje = msgbox('Es turno del Jugador 2');
                            
                        elseif app.bandera == 1
                            
                            if app.score2 > 0
                                app.score2 = app.score2 - 2;
                            end
                            
                            
                            app.bandera = 0;
                            app.mensaje = msgbox('Es turno del Jugador 1');
                        end
                    end
                else
                    app.i = app.c(10);
                    app.j = 10;
                end
            end
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            if (app.j ~= 12) 
                app.Button_12.Icon = app.but12;
                app.count = app.count + 1;
                            
                if (app.count == 2)
                    if (checkImgs(app, app.c(12), app.i))
                        app.Button_12.Enable = 'off';
                        func2(app);
                        app.count = 0;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            app.score1 = app.score1 + 10;
                            app.bandera = 0;
                        elseif app.bandera == 1
                            app.score2 = app.score2 + 10;
                            app.bandera = 1;
                        end
                        
                    else
                        func3(app);
                        app.i = app.c(12);
                        app.j = 12;
                        app.count = app.count - 1;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            
                            if app.score1 > 0
                                app.score1 = app.score1 - 2;
                            end
                            
                            app.bandera = 1;
                            app.mensaje = msgbox('Es turno del Jugador 2');
                            
                        elseif app.bandera == 1
                            
                            if app.score2 > 0
                                app.score2 = app.score2 - 2;
                            end
                            
                            
                            app.bandera = 0;
                            app.mensaje = msgbox('Es turno del Jugador 1');
                        end
                    end
                else
                    app.i = app.c(12);
                    app.j = 12;
                end
            end
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            if (app.j ~= 14) 
                app.Button_14.Icon = app.but14;
                app.count = app.count + 1;
                            
                if (app.count == 2)
                    if (checkImgs(app, app.c(14), app.i))
                        app.Button_14.Enable = 'off';
                        func2(app);
                        app.count = 0;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            app.score1 = app.score1 + 10;
                            app.bandera = 0;
                        elseif app.bandera == 1
                            app.score2 = app.score2 + 10;
                            app.bandera = 1;
                        end
                        
                    else
                        func3(app);
                        app.i = app.c(14);
                        app.j = 14;
                        app.count = app.count - 1;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            
                            if app.score1 > 0
                                app.score1 = app.score1 - 2;
                            end
                            
                            app.bandera = 1;
                            app.mensaje = msgbox('Es turno del Jugador 2');
                            
                        elseif app.bandera == 1
                            
                            if app.score2 > 0
                                app.score2 = app.score2 - 2;
                            end
                            
                            
                            app.bandera = 0;
                            app.mensaje = msgbox('Es turno del Jugador 1');
                        end
                    end
                else
                    app.i = app.c(14);
                    app.j = 14;
                end
            end
        end

        % Button pushed function: Button_18
        function Button_18Pushed(app, event)
            if (app.j ~= 18) 
                app.Button_18.Icon = app.but18;
                app.count = app.count + 1;
                            
                if (app.count == 2)
                    if (checkImgs(app, app.c(18), app.i))
                        app.Button_18.Enable = 'off';
                        func2(app);
                        app.count = 0;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            app.score1 = app.score1 + 10;
                            app.bandera = 0;
                        elseif app.bandera == 1
                            app.score2 = app.score2 + 10;
                            app.bandera = 1;
                        end
                        
                    else
                        func3(app);
                        app.i = app.c(18);
                        app.j = 18;
                        app.count = app.count - 1;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            
                            if app.score1 > 0
                                app.score1 = app.score1 - 2;
                            end
                            
                            app.bandera = 1;
                            app.mensaje = msgbox('Es turno del Jugador 2');
                            
                        elseif app.bandera == 1
                            
                            if app.score2 > 0
                                app.score2 = app.score2 - 2;
                            end
                            
                            
                            app.bandera = 0;
                            app.mensaje = msgbox('Es turno del Jugador 1');
                        end
                    end
                else
                    app.i = app.c(18);
                    app.j = 18;
                end
            end
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            if (app.j ~= 6) 
                app.Button_6.Icon = app.but6;
                app.count = app.count + 1;
                            
                if (app.count == 2)
                    if (checkImgs(app, app.c(6), app.i))
                        app.Button_6.Enable = 'off';
                        func2(app);
                        app.count = 0;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            app.score1 = app.score1 + 10;
                            app.bandera = 0;
                        elseif app.bandera == 1
                            app.score2 = app.score2 + 10;
                            app.bandera = 1;
                        end
                        
                        
                    else
                        func3(app);
                        app.i = app.c(6);
                        app.j = 6;
                        app.count = app.count - 1;
                        
                        %asignación de turnos
                        if app.bandera == 0
                            
                            if app.score1 > 0
                                app.score1 = app.score1 - 2;
                            end
                            
                            app.bandera = 1;
                            app.mensaje = msgbox('Es turno del Jugador 2');
                            
                        elseif app.bandera == 1
                            
                            if app.score2 > 0
                                app.score2 = app.score2 - 2;
                            end
                            
                            
                            app.bandera = 0;
                            app.mensaje = msgbox('Es turno del Jugador 1');
                        end
                    end
                else
                    app.i = app.c(6);
                    app.j = 6;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '';
            app.Button_13 = uibutton(app.UIFigure, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '';
            app.Button_14 = uibutton(app.UIFigure, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '';
            app.Button_15 = uibutton(app.UIFigure, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '';
            app.Button_16 = uibutton(app.UIFigure, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '';
            app.Button_17 = uibutton(app.UIFigure, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_17.Text = '';
            app.Button_18 = uibutton(app.UIFigure, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Button_18Pushed, true);
            app.Button_18.Text = '';
            app.Button_19 = uibutton(app.UIFigure, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Button_19Pushed, true);
            app.Button_19.Text = '';
            app.Button_20 = uibutton(app.UIFigure, 'push');
            app.Button_20.ButtonPushedFcn = createCallbackFcn(app, @Button_20Pushed, true);
            app.Button_20.Text = '';
            app.VamoadarleButton = uibutton(app.UIFigure, 'push');
            app.VamoadarleButton.ButtonPushedFcn = createCallbackFcn(app, @VamoadarleButtonPushed, true);
            app.VamoadarleButton.Text = 'Vamoa darle';
            app.Jugador1EditFieldLabel = uilabel(app.UIFigure);
            app.Jugador1EditFieldLabel.Text = 'Jugador1';
            app.Jugador1 = uieditfield(app.UIFigure, 'numeric');
            app.Jugador2EditFieldLabel = uilabel(app.UIFigure);
            app.Jugador2EditFieldLabel.Text = 'Jugador2';
            app.Jugador2 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fats2parte

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end